classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ColordiversionpointsDropDownLabel  matlab.ui.control.Label
        ColordiversionpointsDropDown  matlab.ui.control.DropDown
        DiversionLabel                matlab.ui.control.Label
        DescriptionDiversionLabel     matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Description
        Colorby % Description
    end
    
    methods (Access = private)
        
        function plotBackground(app)
            cla(app.UIAxes)
            plot(app.UIAxes, app.Data.CV.X, app.Data.CV.Y)
            hold(app.UIAxes,'on');
            plot(app.UIAxes, app.Data.CV.X, app.Data.CV.Y)
            switch app.Colorby
                case 'Uniform'
                    plot(app.UIAxes, [app.Data.DivPoints.X]', [app.Data.DivPoints.Y]', 'o', 'color',[0 0.4470 0.7410],"MarkerSize",4,'MarkerFaceColor', [0 0.4470 0.7410]);
                case 'by Area'
                    scatter(app.UIAxes, [app.Data.DivPoints.X]', [app.Data.DivPoints.Y]', 30, [app.Data.DivPoints.AREA]', 'filled');
                    colormap(app.UIAxes,"cool")
                    
                case 'by Volume'
            end
            axis(app.UIAxes,'equal')
        end
        
        function app = CalculateDivArea(app)
            for ii = 1:length([app.Data.DivPoints.IRDV]')
                elem = [];
                
                id = find([app.Data.DivSpec.IRDV]' == app.Data.DivPoints(ii,1).IRDV);
                
                for jj = 1:length(id)
                    elem = [elem; app.Data.DivSpec(id(jj),1).IERELS];
                end
                elem = unique(elem);
                area = 0;
                for jj = 1:length(elem)
                    if elem(jj) == 0
                        continue;
                    end
                    area = area + polyarea(app.Data.Mesh(elem(jj),1).X(1:end-1), app.Data.Mesh(elem(jj),1).Y(1:end-1));
                end
                if area == 0
                    app.Data.DivPoints(ii,1).AREA = nan;
                else
                    app.Data.DivPoints(ii,1).AREA = area;
                end
            end
        end
        
        function descr = setlabel(app, id, irdv)
            temp = '';
            descr{1,1} = 'Description:';
            for ii = 1:length(id)
                temp = [temp num2str(id(ii)) ' '];
                descr{ii+1,1} = app.Data.DivSpec(id(ii),1).DESCR;
                
            end
            app.DiversionLabel.Text = ['IRDV: '  num2str(irdv) ', IDs: ' temp];
            app.DescriptionDiversionLabel.Text = descr;
            descr = {descr{2:end,:}}';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('c2vsimDiversionData.mat', 'DivSpec');
            load('c2vsimGeometryData.mat', 'c2vsim_riverNodes')
            load('c2vsimGeometryData.mat', 'c2vsim_mesh')
            load('c2vsimDiversionData.mat', 'Diversions');
            load('c2vsimGeometryData.mat', 'c2vsim_outline')
            riv_div = [DivSpec.IRDV]';
            riv_div = unique(riv_div);
            riv_div(riv_div==0,:) =[];
            for ii = 1:length(riv_div)
                id = find([c2vsim_riverNodes.IRV]' == riv_div(ii));
                app.Data.DivPoints(ii,1).X = c2vsim_riverNodes(id,1).X;
                app.Data.DivPoints(ii,1).Y = c2vsim_riverNodes(id,1).Y;
                app.Data.DivPoints(ii,1).IRDV = riv_div(ii);
            end
            
            app.Data.DivSpec = DivSpec;
            app.Data.Mesh = c2vsim_mesh;
            app.Data.DivTimeSeries = Diversions;
            app.Data.CV.X = c2vsim_outline.X;
            app.Data.CV.Y = c2vsim_outline.Y;
            app.Data.t = datetime(1921,9,1)' + calmonths(1:1056);
            app.Colorby = 'Uniform';
            
            app = CalculateDivArea(app);
            
            plotBackground(app)
            axis(app.UIAxes,'off')
            title(app.UIAxes, {'Diversion points','click to show the receiving elements'})
            title(app.UIAxes2, 'Diversion timeseries')
            xlabel(app.UIAxes2,'Year')
            ylabel(app.UIAxes2, 'Cumulative diversion')
            grid(app.UIAxes2,'on')
            app.UIFigure.Position = [100 100 2*820 2*480];
            app.UIAxes.Position = [1 1 2*324 2*480];
            app.UIAxes2.Position = [2*324 2*75 2*450 2*258];
            app.DescriptionDiversionLabel.Position = [224, 758, 450,134];
            app.DescriptionDiversionLabel.FontSize = 16;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            p = app.UIAxes.CurrentPoint;
            [c, d] = min(sqrt(([app.Data.DivPoints.X]' - p(1,1)).^2 + ([app.Data.DivPoints.Y]' - p(1,2)).^2));
            hold(app.UIAxes, 'off');
            hold(app.UIAxes2, 'off');
            elem_id = [];
            if c < 4000
                cla(app.UIAxes);
                cla(app.UIAxes2);
                plotBackground(app)
                %hold(app.UIAxes, 'off');
                id = find([app.Data.DivSpec.IRDV]' == app.Data.DivPoints(d,1).IRDV);
                descr = setlabel(app, [app.Data.DivSpec(id,1).ID]', app.Data.DivPoints(d,1).IRDV);
                for ii = 1:length(id)
                    elem_id = [elem_id; app.Data.DivSpec(id(ii)).IERELS];
                    plot(app.UIAxes2, app.Data.t', cumsum(app.Data.DivTimeSeries.Data(:, app.Data.DivSpec(id(ii)).ICOLRL))) ;
                    legend(app.UIAxes2,descr,'Location','northwest',"FontSize",12);
                    hold(app.UIAxes2, 'on');
                end
                
                for ii = 1: length(elem_id)
                    if elem_id(ii) == 0
                        continue;
                    end
                    patch(app.UIAxes, app.Data.Mesh(elem_id(ii),1).X, app.Data.Mesh(elem_id(ii),1).Y, 'red');
                end
                plot(app.UIAxes, app.Data.DivPoints(d,1).X, app.Data.DivPoints(d,1).Y, 'or',"MarkerSize",6,'MarkerFaceColor', 'r');
                hold(app.UIAxes, 'off')
                
            end
        end

        % Value changed function: ColordiversionpointsDropDown
        function ColordiversionpointsDropDownValueChanged(app, event)
            app.Colorby = app.ColordiversionpointsDropDown.Value;
            plotBackground(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.ColordiversionpointsDropDownLabel = uilabel(app.UIFigure);
            app.ColordiversionpointsDropDownLabel.Text = 'Color diversion points';
            app.ColordiversionpointsDropDown = uidropdown(app.UIFigure);
            app.ColordiversionpointsDropDown.Items = {'Uniform', 'by Area', 'by Volume'};
            app.ColordiversionpointsDropDown.ValueChangedFcn = createCallbackFcn(app, @ColordiversionpointsDropDownValueChanged, true);
            app.ColordiversionpointsDropDown.Value = 'Uniform';
            app.DiversionLabel = uilabel(app.UIFigure);
            app.DiversionLabel.Text = 'IRDV: ';
            app.DescriptionDiversionLabel = uilabel(app.UIFigure);
            app.DescriptionDiversionLabel.Text = 'Description:';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end